classdef MirageMidiManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp_8                         matlab.ui.control.Lamp
        Lamp_7                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        Sequencer8Panel                matlab.ui.container.Panel
        VelocitySpinner_8              matlab.ui.control.Spinner
        VelocitySpinner_8Label         matlab.ui.control.Label
        PatchSpinner_8                 matlab.ui.control.Spinner
        PatchSpinner_9Label            matlab.ui.control.Label
        NoteSpinner_8                  matlab.ui.control.Spinner
        NoteSpinner_8Label             matlab.ui.control.Label
        Sequencer7Panel                matlab.ui.container.Panel
        VelocitySpinner_7              matlab.ui.control.Spinner
        VelocitySpinner_7Label         matlab.ui.control.Label
        PatchSpinner_7                 matlab.ui.control.Spinner
        PatchSpinner_8Label            matlab.ui.control.Label
        NoteSpinner_7                  matlab.ui.control.Spinner
        NoteSpinner_7Label             matlab.ui.control.Label
        Sequencer4Panel                matlab.ui.container.Panel
        VelocitySpinner_4              matlab.ui.control.Spinner
        VelocitySpinner_6Label         matlab.ui.control.Label
        PatchSpinner_4                 matlab.ui.control.Spinner
        PatchSpinner_7Label            matlab.ui.control.Label
        NoteSpinner_4                  matlab.ui.control.Spinner
        NoteSpinner_6Label             matlab.ui.control.Label
        Sequencer3Panel                matlab.ui.container.Panel
        VelocitySpinner_3              matlab.ui.control.Spinner
        VelocitySpinner_5Label         matlab.ui.control.Label
        PatchSpinner_3                 matlab.ui.control.Spinner
        PatchSpinner_6Label            matlab.ui.control.Label
        NoteSpinner_3                  matlab.ui.control.Spinner
        NoteSpinner_5Label             matlab.ui.control.Label
        Sequencer6Panel                matlab.ui.container.Panel
        VelocitySpinner_6              matlab.ui.control.Spinner
        VelocitySpinner_4Label         matlab.ui.control.Label
        PatchSpinner_6                 matlab.ui.control.Spinner
        PatchSpinner_5Label            matlab.ui.control.Label
        NoteSpinner_6                  matlab.ui.control.Spinner
        NoteSpinner_4Label             matlab.ui.control.Label
        Sequencer5Panel                matlab.ui.container.Panel
        VelocitySpinner_5              matlab.ui.control.Spinner
        VelocitySpinner_3Label         matlab.ui.control.Label
        PatchSpinner_5                 matlab.ui.control.Spinner
        PatchSpinner_4Label            matlab.ui.control.Label
        NoteSpinner_5                  matlab.ui.control.Spinner
        NoteSpinner_3Label             matlab.ui.control.Label
        Sequencer2Panel                matlab.ui.container.Panel
        VelocitySpinner_2              matlab.ui.control.Spinner
        VelocitySpinner_2Label         matlab.ui.control.Label
        PatchSpinner_2                 matlab.ui.control.Spinner
        PatchSpinner_3Label            matlab.ui.control.Label
        NoteSpinner_2                  matlab.ui.control.Spinner
        NoteSpinner_2Label             matlab.ui.control.Label
        Sequencer1Panel                matlab.ui.container.Panel
        VelocitySpinner                matlab.ui.control.Spinner
        VelocitySpinnerLabel           matlab.ui.control.Label
        PatchSpinner                   matlab.ui.control.Spinner
        PatchSpinner_2Label            matlab.ui.control.Label
        NoteSpinner                    matlab.ui.control.Spinner
        NoteSpinnerLabel               matlab.ui.control.Label
        DeltaTimesSpinner              matlab.ui.control.Spinner
        DeltaTimesSpinnerLabel         matlab.ui.control.Label
        SequenceLengthSpinner          matlab.ui.control.Spinner
        SequenceLengthLabel            matlab.ui.control.Label
        SequencerStepsListBox          matlab.ui.control.ListBox
        SequencerStepsListBoxLabel     matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        StopGeneratingButton           matlab.ui.control.ToggleButton
        GenerateNotesButton            matlab.ui.control.ToggleButton
        CurrentlyPlayingAudioCheckBox  matlab.ui.control.CheckBox
        MIDIOutputDeviceSelectedCheckBox  matlab.ui.control.CheckBox
        MIDIFileLoadedCheckBox         matlab.ui.control.CheckBox
        MidiFileNameDisplay            matlab.ui.control.TextArea
        PlayMIDIFileButton             matlab.ui.control.Button
        MidiOutDropDown                matlab.ui.control.DropDown
        MIDIOutputDropDownLabel        matlab.ui.control.Label
        MidiInDropDown                 matlab.ui.control.DropDown
        MIDIInputDropDownLabel         matlab.ui.control.Label
        StdOut                         matlab.ui.control.TextArea
        ImportMIDIFileButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        openedFileName % name of imported midi file
        openedFilePath % path to imported midi file
        openedFile % fullfile of imported midi file
        msgArray % converted array of midi messages
        lockTimer % timer object to block overlapping playback
        sequencerStepTimer % timer object for sequencer step
        sequencerCleanupTimer % timer object for sequencer cleanup
    end
    
    methods (Access = private)
        
        function updateMidiDevices(app)
            devices = mididevinfo;
            
            inputDeviceNames = strings(1,1+length(devices.input));
            inputDeviceNames(1) = 'None';
            for k = 1:length(devices.input)
                inputDeviceNames(k+1) = devices.input(k).Name;
            end
            if ~ismember(app.MidiInDropDown.Value,inputDeviceNames)
                app.MidiInDropDown.Value = 'None';
            end
            app.MidiInDropDown.Items = inputDeviceNames;

            outputDeviceNames = strings(1,1+length(devices.output));
            outputDeviceNames(1) = 'None';
            for k = 1:length(devices.output)
                outputDeviceNames(k+1) = devices.output(k).Name;
            end
            if ~ismember(app.MidiOutDropDown.Value,outputDeviceNames)
                app.MidiOutDropDown.Value = 'None';
            end
            app.MidiOutDropDown.Items = outputDeviceNames;
        end
        
        function checkIfCanPlayMidi(app)
            results = true;
            if strcmp(app.MidiOutDropDown.Value,'None')
                results = false;
                app.MIDIOutputDeviceSelectedCheckBox.Value = false;
            else
                app.MIDIOutputDeviceSelectedCheckBox.Value = true;
            end
            if isempty(app.openedFile)
                results = false;
                app.MIDIFileLoadedCheckBox.Value = false;
                app.MidiFileNameDisplay.Value = '';
                app.MidiFileNameDisplay.Enable = 'off';
            else
                app.MIDIFileLoadedCheckBox.Value = true;
                app.MidiFileNameDisplay.Value = app.openedFileName;
                app.MidiFileNameDisplay.Enable = 'on';
            end
            if app.CurrentlyPlayingAudioCheckBox.Value == true
                results = false;
            end

            if results
                app.PlayMIDIFileButton.Enable = 'on';
            else
                app.PlayMIDIFileButton.Enable = 'off';
            end
        end

        function checkIfCanPlaySequencer(app)
            results = true;
            if strcmp(app.MidiOutDropDown.Value,'None')
                results = false;
                app.MIDIOutputDeviceSelectedCheckBox.Value = false;
            else
                app.MIDIOutputDeviceSelectedCheckBox.Value = true;
            end
            if app.CurrentlyPlayingAudioCheckBox.Value == true
                results = false;
            end
            if results
                app.GenerateNotesButton.Enable = 'on';
            else
                app.GenerateNotesButton.Enable = 'off';
            end
        end
        
        function msgArray = convertMidiFileToMessages(app, file)
            readme = fopen(file);
            [readOut, byteCount] = fread(readme);
            fclose(readme);

            % Concatenate ticksPerQNote from 2 bytes
            ticksPerQNote = polyval(readOut(13:14),256);

            % Initialize values
            chunkIndex = 14;     % Header chunk is always 14 bytes
            ts = 0;              % Timestamp - Starts at zero
            BPM = 120;

            msgArray = repmat(midimsg.fromStruct(struct('RawBytes',zeros(1,8),'Timestamp',0)),1,app.midiMsgCounter(file));
            msgIndex = 1;

            format = polyval(readOut(9:10),256);
            % trkCount = polyval(readOut(11:12),256);

            % Parse track chunks in outer loop
            while chunkIndex < byteCount
                chunkLength = polyval(readOut(chunkIndex+(5:8)),256)+8;
                ptr = 8+chunkIndex;
                statusByte = -1;
                while ptr < chunkIndex+chunkLength
                    [deltaTime,deltaLen] = app.findVariableLength(ptr,readOut);
                    ptr = ptr+deltaLen;
                    [statusByte,messageLen,message] = app.interpretMessage(statusByte,ptr,readOut);
                    [ts,msg] = app.createMessage(message,ts,deltaTime,ticksPerQNote,BPM);
                    if ~isempty(msg)
                        msgArray(msgIndex) = msg;
                        msgIndex = msgIndex + 1;
                    end
                    ptr = ptr+messageLen;
                end
                chunkIndex = chunkIndex+chunkLength;
                ts = ts*(format~=1);
            end

        end
        
        function msgCount = midiMsgCounter(app, file)
            readme = fopen(file);
            [readOut, byteCount] = fread(readme);
            fclose(readme);
            chunkIndex = 14;
            msgCount = 0;
            while chunkIndex < byteCount
                chunkLength = polyval(readOut(chunkIndex+(5:8)),256)+8;
                ptr = 8+chunkIndex;
                statusByte = -1;
                while ptr < chunkIndex+chunkLength
                    ptr = ptr+app.qDLen(ptr,readOut);
                    introValue = readOut(ptr+1);
                    if introValue > 127
                        statusByte = introValue;
                        running = false;
                    else
                        running = true;
                    end
                    if ismember(statusByte,[255 240 247])
                        messageLen = (statusByte==255)+1+app.skipVLen(ptr+1+(statusByte==255),readOut);
                        message = -1;
                    elseif running
                        messageLen = app.msgnbytes(statusByte)-1;
                        message = uint8([statusByte;readOut(ptr+(1:messageLen))]);
                    else
                        messageLen = app.msgnbytes(statusByte);
                        message = uint8(readOut(ptr+(1:messageLen)));
                    end
                    if message >= 0
                        msgCount = msgCount + 1;
                    end
                    ptr = ptr+messageLen;
                end
                chunkIndex = chunkIndex+chunkLength;
            end
        end

        function n = msgnbytes(app,sB) %#ok<INUSL> 
            n = 1+2*(sB<=243)-ismember(sB,[192:223,241,243])-2*(sB==240);
        end

        function [valueOut,byteLength] = findVariableLength(app, lengthIndex,readOut) %#ok<INUSL> 
        byteStream = zeros(4,1);
        for i = 1:4
            valCheck = readOut(lengthIndex+i);
            byteStream(i) = bitand(valCheck,127);   % Mask MSB for value
            if ~bitand(valCheck,uint32(128))        % If MSB is 0, no need to append further
                break
            end
        end
        valueOut = polyval(byteStream(1:i),128);    % Base is 128 because 7 bits are used for value
        byteLength = i;
        end

        function byteLength = qDLen(app,lengthIndex,readOut) %#ok<INUSL> 
            byteStream = zeros(4,1);
            for i = 1:4
                valCheck = readOut(lengthIndex+i);
                byteStream(i) = bitand(valCheck,127);
                if ~bitand(valCheck,uint32(128))
                    break
                end
            end
            byteLength = i;
        end
            
        function msgLn = skipVLen(app,lengthIndex,readOut)
                [valueOut,byteLength] = app.findVariableLength(lengthIndex,readOut);
                msgLn = valueOut+byteLength;
        end

        function [tsOut,msgOut] = createMessage(app,messageIn,tsIn,deltaTimeIn,ticksPerQNoteIn,bpmIn) %#ok<INUSL> 
            if messageIn < 0     % Ignore Sysex message/meta-event data
                tsOut = tsIn;
                msgOut = midimsg(0);
                return
            end
            
            % Create RawBytes field
            messageLength = length(messageIn);
            zeroAppend = zeros(8-messageLength,1);
            bytesIn = transpose([messageIn;zeroAppend]);
            
            % deltaTimeIn and ticksPerQNoteIn are both uints
            % Recast both values as doubles
            d = double(deltaTimeIn);
            t = double(ticksPerQNoteIn);
            
            % Create Timestamp field and tsOut
            msPerQNote = 6e7/bpmIn;
            timeAdd = d*(msPerQNote/t)/1e6;
            tsOut = tsIn+timeAdd;
            
            % Create midimsg object
            midiStruct = struct('RawBytes',bytesIn,'Timestamp',tsOut);
            msgOut = midimsg.fromStruct(midiStruct);
        
        end

        function [statusOut,lenOut,message] = interpretMessage(app,statusIn,eventIn,readOut)
            % Check if running status
            introValue = readOut(eventIn+1);
            if introValue > 127
                statusOut = introValue;         % New status
                running = false;
            else
                statusOut = statusIn;           % Running status—Keep old status
                running = true;
            end
            switch statusOut
                case 255     % Meta-event (FF)—IGNORE
                    [eventLength, lengthLen] = app.findVariableLength(eventIn+2, ...
                        readOut);   % Meta-events have an extra byte for type of meta-event
                    lenOut = 2+lengthLen+eventLength;
                    message = -1;
                case 240     % Sysex message (F0)—IGNORE
                    [eventLength, lengthLen] = app.findVariableLength(eventIn+1, ...
                        readOut);
                    lenOut = 1+lengthLen+eventLength;
                    message = -1;
                case 247     % Sysex message (F7)—IGNORE
                    [eventLength, lengthLen] = app.findVariableLength(eventIn+1, ...
                        readOut);
                    lenOut = 1+lengthLen+eventLength;
                    message = -1;
                otherwise    % MIDI message—READ
                    eventLength = app.msgnbytes(statusOut);
                    if running  
                        % Running msgs don't retransmit status—Drop a bit
                        lenOut = eventLength-1;
                        message = uint8([statusOut;readOut(eventIn+(1:lenOut))]);
                    else
                        lenOut = eventLength;
                        message = uint8(readOut(eventIn+(1:lenOut)));
                    end
            end
        end

        function lockPlayback(app,varargin)
            audioDuration = app.msgArray(length(app.msgArray)).Timestamp;
            app.lockTimer = timer('Name','lockTimer','TimerFcn',@(varargin)unlockPlayback(app),'StartDelay',audioDuration);
            app.CurrentlyPlayingAudioCheckBox.Value = true;
            app.checkIfCanPlayMidi();
            app.StdOut.Value = strcat('Playing audio for ~',num2str(audioDuration),' seconds.');
            start(app.lockTimer);
        end

        function unlockPlayback(app,varargin)
            app.CurrentlyPlayingAudioCheckBox.Value = false;
            app.checkIfCanPlayMidi();
            app.StdOut.Value = 'Audio playback complete.';
        end

        function switchToSequencerMode(app)
            app.Lamp.Color = [0 1 0];
            app.Lamp_2.Color = [1 0 0];
            app.Lamp_3.Color = [1 0 0];
            app.Lamp_4.Color = [1 0 0];
            app.Lamp_5.Color = [1 0 0];
            app.Lamp_6.Color = [1 0 0];
            app.Lamp_7.Color = [1 0 0];
            app.Lamp_8.Color = [1 0 0];
            app.Lamp.Enable = 'on';
            app.Lamp_2.Enable = 'on';
            app.Lamp_3.Enable = 'on';
            app.Lamp_4.Enable = 'on';
            app.Lamp_5.Enable = 'on';
            app.Lamp_6.Enable = 'on';
            app.Lamp_7.Enable = 'on';
            app.Lamp_8.Enable = 'on';
            app.CurrentlyPlayingAudioCheckBox.Value = true;
            app.checkIfCanPlayMidi();
            app.StopGeneratingButton.Enable = 'on';
            app.GenerateNotesButton.Enable = 'off';

            tic
            app.stepSequencer(1);
        end

        function switchToMidiFIleMode(app)
            app.Lamp.Color = [1 0 0];
            app.Lamp_2.Color = [1 0 0];
            app.Lamp_3.Color = [1 0 0];
            app.Lamp_4.Color = [1 0 0];
            app.Lamp_5.Color = [1 0 0];
            app.Lamp_6.Color = [1 0 0];
            app.Lamp_7.Color = [1 0 0];
            app.Lamp_8.Color = [1 0 0];
            app.Lamp.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.Lamp_3.Enable = 'off';
            app.Lamp_4.Enable = 'off';
            app.Lamp_5.Enable = 'off';
            app.Lamp_6.Enable = 'off';
            app.Lamp_7.Enable = 'off';
            app.Lamp_8.Enable = 'off';
            app.CurrentlyPlayingAudioCheckBox.Value = false;
            app.checkIfCanPlayMidi();
            app.StopGeneratingButton.Enable = 'off';
            app.GenerateNotesButton.Enable = 'on';
        end
        
        function sendMidi(app,msgs)
            midisend(mididevice('Output',app.MidiOutDropDown.Value),msgs);
        end

        function UpdateProgramChannel_1(app)
            app.sendMidi(midimsg('ProgramChange',1,uint8(app.PatchSpinner_8.Value)));
        end

        function UpdateProgramChannel_2(app)
            app.sendMidi(midimsg('ProgramChange',2,uint8(app.PatchSpinner.Value)));
        end

        function UpdateProgramChannel_3(app)
            app.sendMidi(midimsg('ProgramChange',3,uint8(app.PatchSpinner_2.Value)));
        end

        function UpdateProgramChannel_4(app)
            app.sendMidi(midimsg('ProgramChange',4,uint8(app.PatchSpinner_5.Value)));
        end

        function UpdateProgramChannel_5(app)
            app.sendMidi(midimsg('ProgramChange',5,uint8(app.PatchSpinner_6.Value)));
        end

        function UpdateProgramChannel_6(app)
            app.sendMidi(midimsg('ProgramChange',6,uint8(app.PatchSpinner_3.Value)));
        end

        function UpdateProgramChannel_7(app)
            app.sendMidi(midimsg('ProgramChange',7,uint8(app.PatchSpinner_4.Value)));
        end

        function UpdateProgramChannel_8(app)
            app.sendMidi(midimsg('ProgramChange',8,uint8(app.PatchSpinner_7.Value)));
        end

        function SendSequencer_1(app)
            note = uint8(app.NoteSpinner.Value);
            velocity = uint8(app.VelocitySpinner.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',1,note,velocity);
            off = midimsg('NoteOff',1,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{1} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_2(app)
            note = uint8(app.NoteSpinner_2.Value);
            velocity = uint8(app.VelocitySpinner_2.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',2,note,velocity);
            off = midimsg('NoteOff',2,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{2} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_3(app)
            note = uint8(app.NoteSpinner_3.Value);
            velocity = uint8(app.VelocitySpinner_3.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',3,note,velocity);
            off = midimsg('NoteOff',3,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{3} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_4(app)
            note = uint8(app.NoteSpinner_4.Value);
            velocity = uint8(app.VelocitySpinner_4.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',4,note,velocity);
            off = midimsg('NoteOff',4,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{4} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_5(app)
            note = uint8(app.NoteSpinner_5.Value);
            velocity = uint8(app.VelocitySpinner_5.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',5,note,velocity);
            off = midimsg('NoteOff',5,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{5} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_6(app)
            note = uint8(app.NoteSpinner_6.Value);
            velocity = uint8(app.VelocitySpinner_6.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',6,note,velocity);
            off = midimsg('NoteOff',6,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{6} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_7(app)
            note = uint8(app.NoteSpinner_7.Value);
            velocity = uint8(app.VelocitySpinner_7.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',7,note,velocity);
            off = midimsg('NoteOff',7,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{7} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function SendSequencer_8(app)
            note = uint8(app.NoteSpinner_8.Value);
            velocity = uint8(app.VelocitySpinner_8.Value);
            deltaTime = app.DeltaTimesSpinner.Value;
            on = midimsg('NoteOn',8,note,velocity);
            off = midimsg('NoteOff',8,note,0,deltaTime);
            listItem = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(on);
            app.SequencerStepsListBox.Items{8} = listItem(49:length(listItem));
            app.SequencerStepsListBox.Value = listItem(49:length(listItem));
            app.StdOut.Value = listItem;
            app.sendMidi([on off]);
        end

        function activateStep(app,stepNo)
            switch stepNo
                case 1
                    app.Lamp.Color = [0 1 0];
                    app.UpdateProgramChannel_1();
                    app.SendSequencer_1();
                case 2
                    app.Lamp_2.Color = [0 1 0];
                    app.UpdateProgramChannel_2();
                    app.SendSequencer_2();
                case 3
                    app.Lamp_3.Color = [0 1 0];
                    app.UpdateProgramChannel_3();
                    app.SendSequencer_3();
                case 4
                    app.Lamp_4.Color = [0 1 0];
                    app.UpdateProgramChannel_4();
                    app.SendSequencer_4();
                case 5
                    app.Lamp_5.Color = [0 1 0];
                    app.UpdateProgramChannel_5();
                    app.SendSequencer_5();
                case 6
                    app.Lamp_6.Color = [0 1 0];
                    app.UpdateProgramChannel_6();
                    app.SendSequencer_6();
                case 7
                    app.Lamp_7.Color = [0 1 0];
                    app.UpdateProgramChannel_7();
                    app.SendSequencer_7();
                case 8
                    app.Lamp_8.Color = [0 1 0];
                    app.UpdateProgramChannel_8();
                    app.SendSequencer_8();
            end
        end

        function deactivateStep(app,stepNo)
            switch stepNo
                case 1
                    app.Lamp.Color = [1 0 0];
                case 2
                    app.Lamp_2.Color = [1 0 0];
                case 3
                    app.Lamp_3.Color = [1 0 0];
                case 4
                    app.Lamp_4.Color = [1 0 0];
                case 5
                    app.Lamp_5.Color = [1 0 0];
                case 6
                    app.Lamp_6.Color = [1 0 0];
                case 7
                    app.Lamp_7.Color = [1 0 0];
                case 8
                    app.Lamp_8.Color = [1 0 0];
            end
        end

        function stepSequencer(app,varargin)
            stepNo = varargin{1};
            app.sequencerStepTimer = timer('Name','SquencerStepTimer','TimerFcn',@(varargin)cleanupSequencerStep(app,stepNo),'StartDelay',app.DeltaTimesSpinner.Value);
%             app.StdOut.Value = strcat('running sequencer step #',num2str(stepNo));
            app.activateStep(stepNo);
            start(app.sequencerStepTimer);
            toc
        end

        function cleanupSequencerStep(app,varargin)
            stepNo = varargin{1};
            nextStep = mod(stepNo,uint8(app.SequenceLengthSpinner.Value))+1;
            app.sequencerCleanupTimer = timer('Name','SquencerCleanupTimer','TimerFcn',@(varargin)stepSequencer(app,nextStep),'StartDelay',0.01);
%             app.StdOut.Value = strcat('cleaning up sequencer step #',num2str(stepNo));
            app.deactivateStep(stepNo);
            if strcmp(app.GenerateNotesButton.Enable,'off')
                start(app.sequencerCleanupTimer);
            end 
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Author: Stephen Acomb
            % Written for MiniProject 4 of ECE481 at Rose-Hulman, spr23.
            app.updateMidiDevices();
            app.switchToMidiFIleMode();
            app.checkIfCanPlaySequencer();
        end

        % Button pushed function: ImportMIDIFileButton
        function ImportMIDIFileButtonPushed(app, event)
            
            [filename,filepath] = uigetfile('*.mid');
            if isequal(filename,0)
               app.StdOut.Value = 'User selected Cancel during file selection. No file opened.';
               return
            else
               app.msgArray = [];
               app.openedFile = fullfile(filepath,filename);
               app.openedFileName = filename;
               app.openedFilePath = filepath;
               app.StdOut.Value = strcat('User selected: ',app.openedFileName);
               app.checkIfCanPlayMidi();
            end
        end

        % Drop down opening function: MidiInDropDown
        function MidiInDropDownOpening(app, event)
            app.updateMidiDevices();
            app.switchToMidiFIleMode();
        end

        % Drop down opening function: MidiOutDropDown
        function MidiOutDropDownOpening(app, event)
            app.updateMidiDevices();
            app.checkIfCanPlayMidi();
            app.switchToMidiFIleMode();
        end

        % Value changed function: MidiInDropDown
        function MidiInDropDownValueChanged(app, event)
            app.StdOut.Value = strcat('MIDI Input changed to: "',app.MidiInDropDown.Value,'"');
            app.checkIfCanPlayMidi();
            app.checkIfCanPlaySequencer();
        end

        % Value changed function: MidiOutDropDown
        function MidiOutDropDownValueChanged(app, event)
            app.StdOut.Value = strcat('MIDI Output changed to "',app.MidiOutDropDown.Value,'"');
            app.checkIfCanPlayMidi();
            app.checkIfCanPlaySequencer();
            app.switchToMidiFIleMode();
        end

        % Button pushed function: PlayMIDIFileButton
        function PlayMIDIFileButtonPushed(app, event)
            app.switchToMidiFIleMode();
            if isempty(app.msgArray)
                app.msgArray = app.convertMidiFileToMessages(app.openedFile);
            end
            app.sendMidi(app.msgArray);
            app.lockPlayback();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Generate Notes')
                app.switchToSequencerMode();
            else
                app.switchToMidiFIleMode();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMIDIFileButton = uibutton(app.UIFigure, 'push');
            app.ImportMIDIFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMIDIFileButtonPushed, true);
            app.ImportMIDIFileButton.Text = 'Import MIDI File';
            app.StdOut = uitextarea(app.UIFigure);
            app.StdOut.Editable = 'off';
            app.MIDIInputDropDownLabel = uilabel(app.UIFigure);
            app.MIDIInputDropDownLabel.Text = 'MIDI Input:';
            app.MidiInDropDown = uidropdown(app.UIFigure);
            app.MidiInDropDown.Items = {'None'};
            app.MidiInDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MidiInDropDownOpening, true);
            app.MidiInDropDown.ValueChangedFcn = createCallbackFcn(app, @MidiInDropDownValueChanged, true);
            app.MidiInDropDown.Value = 'None';
            app.MIDIOutputDropDownLabel = uilabel(app.UIFigure);
            app.MIDIOutputDropDownLabel.Text = 'MIDI Output:';
            app.MidiOutDropDown = uidropdown(app.UIFigure);
            app.MidiOutDropDown.Items = {'None'};
            app.MidiOutDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MidiOutDropDownOpening, true);
            app.MidiOutDropDown.ValueChangedFcn = createCallbackFcn(app, @MidiOutDropDownValueChanged, true);
            app.MidiOutDropDown.Value = 'None';
            app.PlayMIDIFileButton = uibutton(app.UIFigure, 'push');
            app.PlayMIDIFileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMIDIFileButtonPushed, true);
            app.PlayMIDIFileButton.Enable = 'off';
            app.PlayMIDIFileButton.Tooltip = {'Import a MIDI file and select an output device to enable.'};
            app.PlayMIDIFileButton.Text = 'Play MIDI File';
            app.MidiFileNameDisplay = uitextarea(app.UIFigure);
            app.MidiFileNameDisplay.Editable = 'off';
            app.MidiFileNameDisplay.Enable = 'off';
            app.MIDIFileLoadedCheckBox = uicheckbox(app.UIFigure);
            app.MIDIFileLoadedCheckBox.Enable = 'off';
            app.MIDIFileLoadedCheckBox.Text = 'MIDI File Loaded';
            app.MIDIOutputDeviceSelectedCheckBox = uicheckbox(app.UIFigure);
            app.MIDIOutputDeviceSelectedCheckBox.Enable = 'off';
            app.MIDIOutputDeviceSelectedCheckBox.Text = 'MIDI Output Device Selected';
            app.CurrentlyPlayingAudioCheckBox = uicheckbox(app.UIFigure);
            app.CurrentlyPlayingAudioCheckBox.Enable = 'off';
            app.CurrentlyPlayingAudioCheckBox.Text = 'Currently Playing Audio';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GenerateNotesButton = uitogglebutton(app.ButtonGroup);
            app.GenerateNotesButton.Text = 'Generate Notes';
            app.StopGeneratingButton = uitogglebutton(app.ButtonGroup);
            app.StopGeneratingButton.Enable = 'off';
            app.StopGeneratingButton.Text = 'Stop Generating';
            app.StopGeneratingButton.Value = true;
            app.SequencerStepsListBoxLabel = uilabel(app.UIFigure);
            app.SequencerStepsListBoxLabel.Text = 'Sequencer Steps:';
            app.SequencerStepsListBox = uilistbox(app.UIFigure);
            app.SequencerStepsListBox.Items = {'Step 1', 'Step 2', 'Step 3', 'Step 4', 'Step 5', 'Step 6', 'Step 7', 'Step 8'};
            app.SequencerStepsListBox.Enable = 'off';
            app.SequencerStepsListBox.Value = 'Step 1';
            app.SequenceLengthLabel = uilabel(app.UIFigure);
            app.SequenceLengthLabel.Text = {'Sequence'; 'Length:'};
            app.SequenceLengthSpinner = uispinner(app.UIFigure);
            app.SequenceLengthSpinner.Limits = [0 8];
            app.SequenceLengthSpinner.Value = 8;
            app.DeltaTimesSpinnerLabel = uilabel(app.UIFigure);
            app.DeltaTimesSpinnerLabel.Text = {'Delta'; 'Times:'};
            app.DeltaTimesSpinner = uispinner(app.UIFigure);
            app.DeltaTimesSpinner.Step = 0.01;
            app.DeltaTimesSpinner.Limits = [0.01 1];
            app.DeltaTimesSpinner.Value = 0.5;
            app.Sequencer1Panel = uipanel(app.UIFigure);
            app.Sequencer1Panel.Title = 'Sequencer 1';
            app.NoteSpinnerLabel = uilabel(app.Sequencer1Panel);
            app.NoteSpinnerLabel.Text = 'Note #';
            app.NoteSpinner = uispinner(app.Sequencer1Panel);
            app.NoteSpinner.Limits = [0 127];
            app.NoteSpinner.Value = 60;
            app.PatchSpinner_2Label = uilabel(app.Sequencer1Panel);
            app.PatchSpinner_2Label.Text = 'Patch #';
            app.PatchSpinner = uispinner(app.Sequencer1Panel);
            app.PatchSpinner.Limits = [0 127];
            app.VelocitySpinnerLabel = uilabel(app.Sequencer1Panel);
            app.VelocitySpinnerLabel.Text = 'Velocity';
            app.VelocitySpinner = uispinner(app.Sequencer1Panel);
            app.VelocitySpinner.Limits = [0 127];
            app.VelocitySpinner.Value = 127;
            app.Sequencer2Panel = uipanel(app.UIFigure);
            app.Sequencer2Panel.Title = 'Sequencer 2';
            app.NoteSpinner_2Label = uilabel(app.Sequencer2Panel);
            app.NoteSpinner_2Label.Text = 'Note #';
            app.NoteSpinner_2 = uispinner(app.Sequencer2Panel);
            app.NoteSpinner_2.Limits = [0 127];
            app.NoteSpinner_2.Value = 60;
            app.PatchSpinner_3Label = uilabel(app.Sequencer2Panel);
            app.PatchSpinner_3Label.Text = 'Patch #';
            app.PatchSpinner_2 = uispinner(app.Sequencer2Panel);
            app.PatchSpinner_2.Limits = [0 127];
            app.VelocitySpinner_2Label = uilabel(app.Sequencer2Panel);
            app.VelocitySpinner_2Label.Text = 'Velocity';
            app.VelocitySpinner_2 = uispinner(app.Sequencer2Panel);
            app.VelocitySpinner_2.Limits = [0 127];
            app.VelocitySpinner_2.Value = 127;
            app.Sequencer5Panel = uipanel(app.UIFigure);
            app.Sequencer5Panel.Title = 'Sequencer 5';
            app.NoteSpinner_3Label = uilabel(app.Sequencer5Panel);
            app.NoteSpinner_3Label.Text = 'Note #';
            app.NoteSpinner_5 = uispinner(app.Sequencer5Panel);
            app.NoteSpinner_5.Limits = [0 127];
            app.NoteSpinner_5.Value = 60;
            app.PatchSpinner_4Label = uilabel(app.Sequencer5Panel);
            app.PatchSpinner_4Label.Text = 'Patch #';
            app.PatchSpinner_5 = uispinner(app.Sequencer5Panel);
            app.PatchSpinner_5.Limits = [0 127];
            app.VelocitySpinner_3Label = uilabel(app.Sequencer5Panel);
            app.VelocitySpinner_3Label.Text = 'Velocity';
            app.VelocitySpinner_5 = uispinner(app.Sequencer5Panel);
            app.VelocitySpinner_5.Limits = [0 127];
            app.VelocitySpinner_5.Value = 127;
            app.Sequencer6Panel = uipanel(app.UIFigure);
            app.Sequencer6Panel.Title = 'Sequencer 6';
            app.NoteSpinner_4Label = uilabel(app.Sequencer6Panel);
            app.NoteSpinner_4Label.Text = 'Note #';
            app.NoteSpinner_6 = uispinner(app.Sequencer6Panel);
            app.NoteSpinner_6.Limits = [0 127];
            app.NoteSpinner_6.Value = 60;
            app.PatchSpinner_5Label = uilabel(app.Sequencer6Panel);
            app.PatchSpinner_5Label.Text = 'Patch #';
            app.PatchSpinner_6 = uispinner(app.Sequencer6Panel);
            app.PatchSpinner_6.Limits = [0 127];
            app.VelocitySpinner_4Label = uilabel(app.Sequencer6Panel);
            app.VelocitySpinner_4Label.Text = 'Velocity';
            app.VelocitySpinner_6 = uispinner(app.Sequencer6Panel);
            app.VelocitySpinner_6.Limits = [0 127];
            app.VelocitySpinner_6.Value = 127;
            app.Sequencer3Panel = uipanel(app.UIFigure);
            app.Sequencer3Panel.Title = 'Sequencer 3';
            app.NoteSpinner_5Label = uilabel(app.Sequencer3Panel);
            app.NoteSpinner_5Label.Text = 'Note #';
            app.NoteSpinner_3 = uispinner(app.Sequencer3Panel);
            app.NoteSpinner_3.Limits = [0 127];
            app.NoteSpinner_3.Value = 60;
            app.PatchSpinner_6Label = uilabel(app.Sequencer3Panel);
            app.PatchSpinner_6Label.Text = 'Patch #';
            app.PatchSpinner_3 = uispinner(app.Sequencer3Panel);
            app.PatchSpinner_3.Limits = [0 127];
            app.VelocitySpinner_5Label = uilabel(app.Sequencer3Panel);
            app.VelocitySpinner_5Label.Text = 'Velocity';
            app.VelocitySpinner_3 = uispinner(app.Sequencer3Panel);
            app.VelocitySpinner_3.Limits = [0 127];
            app.VelocitySpinner_3.Value = 127;
            app.Sequencer4Panel = uipanel(app.UIFigure);
            app.Sequencer4Panel.Title = 'Sequencer 4';
            app.NoteSpinner_6Label = uilabel(app.Sequencer4Panel);
            app.NoteSpinner_6Label.Text = 'Note #';
            app.NoteSpinner_4 = uispinner(app.Sequencer4Panel);
            app.NoteSpinner_4.Limits = [0 127];
            app.NoteSpinner_4.Value = 60;
            app.PatchSpinner_7Label = uilabel(app.Sequencer4Panel);
            app.PatchSpinner_7Label.Text = 'Patch #';
            app.PatchSpinner_4 = uispinner(app.Sequencer4Panel);
            app.PatchSpinner_4.Limits = [0 127];
            app.VelocitySpinner_6Label = uilabel(app.Sequencer4Panel);
            app.VelocitySpinner_6Label.Text = 'Velocity';
            app.VelocitySpinner_4 = uispinner(app.Sequencer4Panel);
            app.VelocitySpinner_4.Limits = [0 127];
            app.VelocitySpinner_4.Value = 127;
            app.Sequencer7Panel = uipanel(app.UIFigure);
            app.Sequencer7Panel.Title = 'Sequencer 7';
            app.NoteSpinner_7Label = uilabel(app.Sequencer7Panel);
            app.NoteSpinner_7Label.Text = 'Note #';
            app.NoteSpinner_7 = uispinner(app.Sequencer7Panel);
            app.NoteSpinner_7.Limits = [0 127];
            app.NoteSpinner_7.Value = 60;
            app.PatchSpinner_8Label = uilabel(app.Sequencer7Panel);
            app.PatchSpinner_8Label.Text = 'Patch #';
            app.PatchSpinner_7 = uispinner(app.Sequencer7Panel);
            app.PatchSpinner_7.Limits = [0 127];
            app.VelocitySpinner_7Label = uilabel(app.Sequencer7Panel);
            app.VelocitySpinner_7Label.Text = 'Velocity';
            app.VelocitySpinner_7 = uispinner(app.Sequencer7Panel);
            app.VelocitySpinner_7.Limits = [0 127];
            app.VelocitySpinner_7.Value = 127;
            app.Sequencer8Panel = uipanel(app.UIFigure);
            app.Sequencer8Panel.Title = 'Sequencer 8';
            app.NoteSpinner_8Label = uilabel(app.Sequencer8Panel);
            app.NoteSpinner_8Label.Text = 'Note #';
            app.NoteSpinner_8 = uispinner(app.Sequencer8Panel);
            app.NoteSpinner_8.Limits = [0 127];
            app.NoteSpinner_8.Value = 60;
            app.PatchSpinner_9Label = uilabel(app.Sequencer8Panel);
            app.PatchSpinner_9Label.Text = 'Patch #';
            app.PatchSpinner_8 = uispinner(app.Sequencer8Panel);
            app.PatchSpinner_8.Limits = [0 127];
            app.VelocitySpinner_8Label = uilabel(app.Sequencer8Panel);
            app.VelocitySpinner_8Label.Text = 'Velocity';
            app.VelocitySpinner_8 = uispinner(app.Sequencer8Panel);
            app.VelocitySpinner_8.Limits = [0 127];
            app.VelocitySpinner_8.Value = 127;
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MirageMidiManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end